classdef MSpecMainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MSPECAppUIFigure                matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        NewProjectMenu                  matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveAsMenu                      matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        ContactUsMenu                   matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PushTool3                       matlab.ui.container.toolbar.PushTool
        PushTool9                       matlab.ui.container.toolbar.PushTool
        PushTool8                       matlab.ui.container.toolbar.PushTool
        PushTool4                       matlab.ui.container.toolbar.PushTool
        PushTool7                       matlab.ui.container.toolbar.PushTool
        PushTool6                       matlab.ui.container.toolbar.PushTool
        TabGroup                        matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        PreprocessingLabel              matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        ImportStatusLabel               matlab.ui.control.Label
        RecentFilesPanel                matlab.ui.container.Panel
        OpenButton                      matlab.ui.control.Button
        RecentFileListBox               matlab.ui.control.ListBox
        CreateNewProjectPanel           matlab.ui.container.Panel
        DescriptionEditField            matlab.ui.control.TextArea
        DescriptionTextAreaLabel_2      matlab.ui.control.Label
        Import_CreateProjectButton      matlab.ui.control.Button
        NumberofMassSpectraEditField    matlab.ui.control.NumericEditField
        NumberofMassSpectraLabel        matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        HeightField                     matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        WidthField                      matlab.ui.control.NumericEditField
        DimensionLabel                  matlab.ui.control.Label
        DataTypesButtonGroup            matlab.ui.container.ButtonGroup
        Imaging2DButton                 matlab.ui.control.RadioButton
        MassSpectra1DButton             matlab.ui.control.RadioButton
        ProjectNameEditField            matlab.ui.control.EditField
        ProjectNameEditFieldLabel       matlab.ui.control.Label
        Import_ImportButton             matlab.ui.control.Button
        ImportyourmassspectrometrydatatobeginLabel  matlab.ui.control.Label
        WelcometoMSpecLabel             matlab.ui.control.Label
        PreprocessingTab                matlab.ui.container.Tab
        Preprocessing_SaveButton        matlab.ui.control.Button
        Preprocessing_ExportButton      matlab.ui.control.Button
        PlotPanel                       matlab.ui.container.Panel
        Preprocessing_PlotButton        matlab.ui.control.Button
        Preprocessing_EndingpointEditField  matlab.ui.control.EditField
        EndingpointEditFieldLabel       matlab.ui.control.Label
        Preprocessing_StartingpointEditField  matlab.ui.control.EditField
        StartingpointEditFieldLabel     matlab.ui.control.Label
        Preprocessing_SectionofinterestLabel  matlab.ui.control.Label
        Preprocessing_SpectrumtodisplayEditField  matlab.ui.control.EditField
        SpectrumtodisplayEditFieldLabel  matlab.ui.control.Label
        PeakAlignmentPanel              matlab.ui.container.Panel
        MaximumshiftallowedEditFieldLabel  matlab.ui.control.Label
        Preprocessing_MaximumshiftallowedEditField  matlab.ui.control.NumericEditField
        MinimumsegmentsizeallowedLabel  matlab.ui.control.Label
        Preprocessing_MinimumsegementsizeallowedEditField  matlab.ui.control.NumericEditField
        ReferenceSpectrumEditFieldLabel  matlab.ui.control.Label
        Preprocessing_ReferenceSpectrumEditField  matlab.ui.control.NumericEditField
        BaselineCorrectionPanel         matlab.ui.container.Panel
        QuantilevalueEditFieldLabel     matlab.ui.control.Label
        Preprocessing_QuantilevalueEditField  matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel          matlab.ui.control.Label
        Preprocessing_StepsizeEditField  matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        Preprocessing_WindowSizeEditField  matlab.ui.control.NumericEditField
        PreProcessingToolLabel          matlab.ui.control.Label
        Preprocessing_RawMSPlot         matlab.ui.control.UIAxes
        Preprocessing_PreprocessedMSPlot  matlab.ui.control.UIAxes
        NormalizationTab                matlab.ui.container.Tab
        DataNormalizationLabel          matlab.ui.control.Label
        Normalization_DataTable         matlab.ui.control.Table
        ExportFilesPanel                matlab.ui.container.Panel
        Normalization_SaveButton        matlab.ui.control.Button
        Normalization_ExportCSVNormalizedDataButton  matlab.ui.control.Button
        ViewOptionButtonGroup           matlab.ui.container.ButtonGroup
        Normalization_MultipleSpectraButton  matlab.ui.control.RadioButton
        Normalization_SelectDataTable   matlab.ui.control.Table
        Normalization_SamplePointSpinner  matlab.ui.control.Spinner
        SamplePointSpinnerLabel         matlab.ui.control.Label
        Normalization_SingleSpectrumButton  matlab.ui.control.RadioButton
        Normalization_AllSpectraButton  matlab.ui.control.RadioButton
        NormalizationMethodsButtonGroup  matlab.ui.container.ButtonGroup
        Normalization_MaxIntensityButton  matlab.ui.control.ToggleButton
        Normalization_EditRefMZ         matlab.ui.control.Button
        Normalization_ReferenceMZEditField  matlab.ui.control.NumericEditField
        ReferenceMZLabel                matlab.ui.control.Label
        Normalize_StartButton           matlab.ui.control.Button
        Normalization_NoiseLevelButton  matlab.ui.control.ToggleButton
        Normalization_MedianButton      matlab.ui.control.ToggleButton
        Normalization_pnormButton       matlab.ui.control.ToggleButton
        Normalization_pvalueSpinner     matlab.ui.control.Spinner
        pvalueSpinnerLabel              matlab.ui.control.Label
        Normalization_ReferencePeakButton  matlab.ui.control.ToggleButton
        Normalization_ioncountsButton   matlab.ui.control.ToggleButton
        Normalization_NormalizedMSPlot  matlab.ui.control.UIAxes
        Normalization_ProcessedMSPlot   matlab.ui.control.UIAxes
        PeakDetectionTab                matlab.ui.container.Tab
        ExportCMZButton                 matlab.ui.control.Button
        TotalnoofcommonpeaksEditField   matlab.ui.control.NumericEditField
        TotalnoofcommonpeaksEditFieldLabel  matlab.ui.control.Label
        TotalnoofpeaksEditField         matlab.ui.control.NumericEditField
        TotalnoofpeaksEditFieldLabel    matlab.ui.control.Label
        SampleAlignmentPanel            matlab.ui.container.Panel
        Detection_BANDEditField         matlab.ui.control.NumericEditField
        MaximumallowabledistanceLabel   matlab.ui.control.Label
        Detection_StartDetectionButton_3  matlab.ui.control.Button
        Binning_MethodDropDown_2        matlab.ui.control.DropDown
        MethodDropDownLabel_3           matlab.ui.control.Label
        NextButton                      matlab.ui.control.Button
        Detection_SpectrumtodisplayEditField  matlab.ui.control.EditField
        SpectrumtodisplayoptionalLabel  matlab.ui.control.Label
        PeakDetectionLabel              matlab.ui.control.Label
        ExportPeakListButton            matlab.ui.control.Button
        BiningPanel                     matlab.ui.container.Panel
        CUTOFFSpinner                   matlab.ui.control.Spinner
        CUTOFFSpinnerLabel              matlab.ui.control.Label
        Detection_StartDetectionButton_2  matlab.ui.control.Button
        Binning_CriterionDropDown       matlab.ui.control.DropDown
        CriterionDropDownLabel          matlab.ui.control.Label
        Binning_MethodDropDown          matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        PeakDetectionPanel              matlab.ui.container.Panel
        Detection_MethodDropDown        matlab.ui.control.DropDown
        MethodDropDownLabel_2           matlab.ui.control.Label
        Detection_StartDetectionButton  matlab.ui.control.Button
        Detection_PeakThresholdEditField  matlab.ui.control.NumericEditField
        PeakThresholdEditFieldLabel     matlab.ui.control.Label
        Detection_MULTIPLIEREditField   matlab.ui.control.NumericEditField
        MULTIPLIEREditFieldLabel        matlab.ui.control.Label
        Detection_HEIGHTFILTEREditField  matlab.ui.control.NumericEditField
        HEIGHTFILTEREditFieldLabel      matlab.ui.control.Label
        Detection_BASEEditField         matlab.ui.control.NumericEditField
        BASEEditFieldLabel              matlab.ui.control.Label
        Binning_AlignedPeakBinningPlot  matlab.ui.control.UIAxes
        Binning_PeakBinningPlot         matlab.ui.control.UIAxes
        Detection_PeakDetectionPlot     matlab.ui.control.UIAxes
        PeakBinningTab                  matlab.ui.container.Tab
        Binning_Table                   matlab.ui.control.Table
        PeakBinningLabel                matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CalculatedEdgesLabel            matlab.ui.control.Label
        CMZLabel                        matlab.ui.control.Label
        Binning_ViewOptionButtonGroup   matlab.ui.container.ButtonGroup
        Binning_MultipleSpectraButton   matlab.ui.control.RadioButton
        Binning_SelectDataTable         matlab.ui.control.Table
        Binning_SamplePointSpinner      matlab.ui.control.Spinner
        SamplePointSpinnerLabel_2       matlab.ui.control.Label
        Binning_SingleSpectrumButton    matlab.ui.control.RadioButton
        Binning_AllSpectraButton        matlab.ui.control.RadioButton
        PeakBinningPanel                matlab.ui.container.Panel
        Binning_ExportBinButton         matlab.ui.control.Button
        Binning_FileLabel               matlab.ui.control.Label
        Binning_ImportEdgeButton        matlab.ui.control.Button
        Binning_ImportEdgesCheckBox     matlab.ui.control.CheckBox
        Binning_ExportButton            matlab.ui.control.Button
        Binning_ToleranceEditField      matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        Binning_NumberofBinsSpinner     matlab.ui.control.Spinner
        MaximumnoofpeaksineachbinLabel  matlab.ui.control.Label
        Binning_BinningMethod           matlab.ui.control.DropDown
        MethodDropDownLabel_4           matlab.ui.control.Label
        Binning_StartBinningButton      matlab.ui.control.Button
        Binning_EdgeListPlot            matlab.ui.control.UIAxes
        PeakBinningFinalPlot            matlab.ui.control.UIAxes
        ProjectInfoTab                  matlab.ui.container.Tab
        Hyperlink                       matlab.ui.control.Hyperlink
        Label_4                         matlab.ui.control.Label
        AboutMSpecLabel                 matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        AboutYourProjectPanel           matlab.ui.container.Panel
        DataExportPanel                 matlab.ui.container.Panel
        ExportSettingsButton            matlab.ui.control.Button
        StepsLabel_2                    matlab.ui.control.Label
        Export_PeakBinningCheckBox      matlab.ui.control.CheckBox
        ExportCSVFilesButton            matlab.ui.control.Button
        Export_PreprocessingCheckBox    matlab.ui.control.CheckBox
        Export_NormalizationCheckBox    matlab.ui.control.CheckBox
        Export_PeakDetectionCheckBox    matlab.ui.control.CheckBox
        ProjectInfo_CreatedDate         matlab.ui.control.EditField
        CreatedLabel                    matlab.ui.control.Label
        ReportGenerationPanel           matlab.ui.container.Panel
        StepsLabel                      matlab.ui.control.Label
        PeakBinningCheckBox             matlab.ui.control.CheckBox
        GeneratePDFReportButton         matlab.ui.control.Button
        PreprocessingCheckBox           matlab.ui.control.CheckBox
        NormalizationCheckBox           matlab.ui.control.CheckBox
        PeakDetectionCheckBox           matlab.ui.control.CheckBox
        EditButton                      matlab.ui.control.StateButton
        ProjectInfo_DescriptionTextArea  matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        ProjectInfo_ImportedFileEditField  matlab.ui.control.EditField
        ImportedFileEditFieldLabel      matlab.ui.control.Label
        ProjectInfo_ProjectNameEditField  matlab.ui.control.EditField
        ProjectNameEditField_2Label     matlab.ui.control.Label
    end


    properties (Access = public)

        CurrentProject MSProject; %current project
        MZPickerApp % app to select ref MZ

    end

    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            MSpecController.getRecentFiles(app);
        end

        % Callback function
        function NewProjectMenuSelected(app, event)
            %if isNull(app.CurrentProject)
            %app.TabGroup.SelectedTab = app.ImportTab;
            %end
            %[file, path] = uigetfile('*.csv');
            %if isequal(file,0)
            %msgbox('Please input CSV files')
            %else
            %app.FileName = file;
            %app.DataArray = csvread(fullfile(path,file));
            % Data Array Preprocess and Properties
            % column 1 is used for plotting the x-axis
            %app.XAxisPlot = app.DataArray(:, 1);
            % removing the first column from the Array
            %app.DataArray(:,1) = [];
            % removing last col ( all 0s )
            % app.DataArray(:,81) = [];

            %plotting the raw data
            %[app.RowNumber,app.ColumnNumber]= size(app.DataArray);
            %hold(app.Normalization_ProcessedMSPlot,"on");
            %for i = 1:app.ColumnNumber
            %stem(app.Normalization_ProcessedMSPlot,app.XAxisPlot,app.DataArray(:, i));
            %end
            %hold(app.Normalization_ProcessedMSPlot,"off");

            %updateNormalizedArray(app);
            %setDefaultReferencePeak(app);
            %DisplaySamplePointOption(app);
            %DEMO_DisplayClassOption(app);
            %end
        end

        % Selection changed function: 
        % NormalizationMethodsButtonGroup
        function NormalizationMethodsButtonGroupSelectionChanged(app, event)
            selectedButton = app.NormalizationMethodsButtonGroup.SelectedObject;
            app.Normalization_pvalueSpinner.Enable = false;
            switch selectedButton % Get Tag of selected object.
                case app.Normalization_ioncountsButton
                    app.CurrentProject.PreprocessedData.NormalizeMethod='Sum';
                case app.Normalization_pnormButton
                    app.CurrentProject.PreprocessedData.NormalizeMethod='Norm';
                    app.Normalization_pvalueSpinner.Enable = true;
                case app.Normalization_MedianButton
                    app.CurrentProject.PreprocessedData.NormalizeMethod='Median';
                case app.Normalization_NoiseLevelButton
                    app.CurrentProject.PreprocessedData.NormalizeMethod='Noise';
                case app.Normalization_MaxIntensityButton
                    app.CurrentProject.PreprocessedData.NormalizeMethod='Max';
                otherwise
                    app.CurrentProject.PreprocessedData.NormalizeMethod='Peak';
                    % init MZ Picker App
                    app.MZPickerApp = MZPicker(app);
            end
        end

        % Callback function
        function FeaturePeakSliderValueChanged(app, event)
            value = app.FeaturePeakSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MinorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MinorTicks(minIdx);
            app.FeaturePeak = value;
            app.SelectedPeakLabel.Text = ['Selected Peak:',num2str(app.FeaturePeak)];
            plotNormalizedData(app);
        end

        % Callback function
        function Normalization_PeakSpinnerValueChanged(app, event)
            app.CurrentProject.PreprocessedData.ReferencePeak = app.Normalization_PeakSpinner.Value;
            %MSpecController.updateNormalizedSpectra(app);
        end

        % Button pushed function: 
        % Normalization_ExportCSVNormalizedDataButton
        function Normalization_ExportCSVNormalizedDataButtonPushed(app, event)
            DataImportExport.exportNormalizedData(app);
        end

        % Value changed function: Normalization_pvalueSpinner
        function Normalization_pvalueSpinnerValueChanged(app, event)
            app.CurrentProject.PreprocessedData.NormalizationNormValue = app.Normalization_pvalueSpinner.Value;
        end

        % Callback function
        function DegreeSpinnerValueChanged(app, event)
            app.RegressionDegree = app.DegreeSpinner.Value;
        end

        % Selection changed function: ViewOptionButtonGroup
        function ViewOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewOptionButtonGroup.SelectedObject;
            app.Normalization_SamplePointSpinner.Enable = false;
            app.Normalization_SelectDataTable.Enable = 'off';
            switch selectedButton % Get Tag of selected object.
                case app.Normalization_AllSpectraButton
                    app.CurrentProject.PreprocessedData.NormalizeDisplay='All';
                case app.Normalization_SingleSpectrumButton
                    app.CurrentProject.PreprocessedData.NormalizeDisplay='Single';
                    app.Normalization_SamplePointSpinner.Enable = true;
                otherwise
                    app.CurrentProject.PreprocessedData.NormalizeDisplay='Multiple';
                    app.Normalization_SelectDataTable.Enable = 'on';
            end
            Visualization.plotNormalizedSpectra(app);
        end

        % Value changed function: Normalization_SamplePointSpinner
        function Normalization_SamplePointSpinnerValueChanged(app, event)
            Visualization.plotNormalizedSpectra(app);
        end

        % Cell edit callback: Normalization_SelectDataTable
        function Normalization_SelectDataTableCellEdit(app, event)
            Visualization.plotNormalizedSpectra(app);
        end

        % Callback function
        function ColorMapDropDownValueChanged(app, event)
            colormap(app.Imaging_Display,app.ColorMapDropDown.Value);
        end

        % Callback function
        function SelectClassTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            DEMO_Analysis_Image(app);
        end

        % Button pushed function: Import_ImportButton
        function ImportButton_Pushed(app, event)
            DataImportExport.importData(app);
        end

        % Button pushed function: Import_CreateProjectButton
        function Import_CreateProjectButtonPushed(app, event)
            DataImportExport.createProject(app);
            app.SaveMenu.Enable = 'on';
        end

        % Button pushed function: Preprocessing_PlotButton
        function Preprocessing_PlotButtonPushed(app, event)
            MSpecController.plotButtonPushedHandler(app);
        end

        % Button pushed function: Preprocessing_SaveButton
        function Preprocessing_SaveButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.NormalizationTab;
        end

        % Button pushed function: Detection_StartDetectionButton
        function Detection_StartDetectionButtonPushed(app, event)
            MSpecController.startPeakDetection(app);
        end

        % Value changed function: Detection_MethodDropDown
        function Detection_MethodDropDownValueChanged(app, event)
            value = app.Detection_MethodDropDown.Value;
            switch value % Get Tag of selected object.
                case 'Auto'
                    app.Detection_BASEEditField.Enable = false;
                    app.Detection_MULTIPLIEREditField.Enable = false;
                    app.Detection_HEIGHTFILTEREditField.Enable = false;
                    app.Detection_PeakThresholdEditField.Enable = false;
                otherwise
                    app.Detection_BASEEditField.Enable = true;
                    app.Detection_MULTIPLIEREditField.Enable = true;
                    app.Detection_HEIGHTFILTEREditField.Enable = true;
                    app.Detection_PeakThresholdEditField.Enable = true;
            end
        end

        % Button pushed function: Detection_StartDetectionButton_2
        function Detection_StartDetectionButton_2Pushed(app, event)
            MSpecController.startPeakBinning(app);
        end

        % Button pushed function: Detection_StartDetectionButton_3
        function Detection_StartDetectionButton_3Pushed(app, event)
            MSpecController.startAlignPeakBinning(app)
        end

        % Button pushed function: Binning_StartBinningButton
        function Binning_StartBinningButtonPushed(app, event)
            MSpecController.startRealPeakBinning(app);
        end

        % Button pushed function: Preprocessing_ExportButton
        function Preprocessing_ExportButtonPushed(app, event)
            DataImportExport.exportPreprocessedData(app);
        end

        % Selection changed function: Binning_ViewOptionButtonGroup
        function Binning_ViewOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.Binning_ViewOptionButtonGroup.SelectedObject;
            app.Binning_SamplePointSpinner.Enable = false;
            app.Binning_SelectDataTable.Enable = 'off';
            switch selectedButton % Get Tag of selected object.
                case app.Binning_AllSpectraButton
                    app.CurrentProject.PreprocessedData.BinningDisplay='All';
                case app.Binning_SingleSpectrumButton
                    app.CurrentProject.PreprocessedData.BinningDisplay='Single';
                    app.Binning_SamplePointSpinner.Enable = true;
                otherwise
                    app.CurrentProject.PreprocessedData.BinningDisplay='Multiple';
                    app.Binning_SelectDataTable.Enable = 'on';
            end
            MSpecController.Binning_updateBinningPlot(app);
        end

        % Value changed function: Binning_SamplePointSpinner
        function Binning_SamplePointSpinnerValueChanged(app, event)
            MSpecController.Binning_updateBinningPlot(app);
        end

        % Cell edit callback: Binning_SelectDataTable
        function Binning_SelectDataTableCellEdit(app, event)
            MSpecController.Binning_updateBinningPlot(app);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            MSpecController.loadRecentFiles(app);
        end

        % Button pushed function: Binning_ExportButton
        function Binning_ExportButtonPushed(app, event)
            DataImportExport.exportBinnedData(app);
        end

        % Value changed function: Binning_ImportEdgesCheckBox
        function Binning_ImportEdgesCheckBoxValueChanged(app, event)
            value = app.Binning_ImportEdgesCheckBox.Value;
            switch value
                case true
                    app.Binning_ImportEdgeButton.Enable = true;
                    app.Binning_BinningMethod.Enable = false;
                    app.Binning_NumberofBinsSpinner.Enable = false;
                    app.Binning_ToleranceEditField.Enable = false;
                otherwise
                    app.Binning_ImportEdgeButton.Enable = false;
                    app.Binning_BinningMethod.Enable = true;
                    app.Binning_NumberofBinsSpinner.Enable = true;
                    app.Binning_ToleranceEditField.Enable = true;
            end
        end

        % Button pushed function: Binning_ExportBinButton
        function Binning_ExportBinButtonPushed(app, event)
            DataImportExport.exportBinEdges(app);
        end

        % Button pushed function: Binning_ImportEdgeButton
        function Binning_ImportEdgeButtonPushed(app, event)
            DataImportExport.importBinEdges(app);
        end

        % Value changed function: EditButton
        function EditButtonValueChanged(app, event)
            value = app.EditButton.Value;
            if value==1
                % Enable Edit Field
                app.EditButton.Text = 'Save';
                app.ProjectInfo_DescriptionTextArea.Editable = true;
            else
                % Save
                app.CurrentProject.Description = app.ProjectInfo_DescriptionTextArea.Value;
                app.EditButton.Text = 'Edit';
                app.ProjectInfo_DescriptionTextArea.Editable = false;
            end
        end

        % Button pushed function: Normalize_StartButton
        function Normalize_StartButtonPushed(app, event)
            MSpecController.updateNormalizedSpectra(app);
        end

        % Button pushed function: Normalization_EditRefMZ
        function Normalization_EditRefMZPushed(app, event)
            app.MZPickerApp = MZPicker(app);
        end

        % Value changed function: Normalization_ReferenceMZEditField
        function Normalization_ReferenceMZEditFieldValueChanged(app, event)
            app.CurrentProject.PreprocessedData.ReferencePeak = app.Normalization_ReferenceMZEditField.Value;
        end

        % Button pushed function: ExportCSVFilesButton
        function ExportCSVFilesButtonPushed(app, event)
            DataImportExport.exportAllData(app);
        end

        % Button pushed function: ExportPeakListButton
        function ExportPeakListButtonPushed(app, event)
            DataImportExport.exportDetectedPeak(app);
        end

        % Button pushed function: GeneratePDFReportButton
        function GeneratePDFReportButtonPushed(app, event)
            ReportGeneration.generatePDFReport(app);
        end

        % Callback function: PushTool8, SaveAsMenu
        function PushTool8Clicked(app, event)
           MSpecController.saveAs(app);
        end

        % Selection changed function: DataTypesButtonGroup
        function DataTypesButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataTypesButtonGroup.SelectedObject;
            switch selectedButton
                case app.Imaging2DButton
                    app.HeightField.Editable = 'on';
                    app.WidthField.Editable = 'on';
                otherwise
                    app.HeightField.Editable = 'off';
                    app.WidthField.Editable = 'off';
            end
        end

        % Button pushed function: ExportSettingsButton
        function ExportSettingsButtonPushed(app, event)
            DataImportExport.exportPreprocessingSettings(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.PeakBinningTab;
        end

        % Button pushed function: Normalization_SaveButton
        function Normalization_SaveButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.PeakDetectionTab;
        end

        % Callback function: ExitMenu, MSPECAppUIFigure
        function MSPECAppUIFigureCloseRequest(app, event)
            if ~isempty(app.CurrentProject)
                MSpecController.requestExit(app);
            else
                delete(app);
            end
        end

        % Callback function: NewProjectMenu, PushTool
        function PushToolClicked(app, event)
            app.TabGroup.SelectedTab = app.ImportTab;
            DataImportExport.importData(app);
        end

        % Clicked callback: PushTool4
        function PushTool4Clicked(app, event)
            app.TabGroup.SelectedTab = app.ProjectInfoTab;
            DataImportExport.exportAllData(app);
        end

        % Clicked callback: PushTool7
        function PushTool7Clicked(app, event)
            app.TabGroup.SelectedTab = app.ProjectInfoTab;
            ReportGeneration.generatePDFReport(app);
        end

        % Button pushed function: ExportCMZButton
        function ExportCMZButtonPushed(app, event)
             DataImportExport.exportCMZ(app);
        end

        % Menu selected function: ContactUsMenu
        function ContactUsMenuSelected(app, event)
            msgbox({'Thank you for all the love and support for MSpec! ♥';'';'Please feel free to email with any comments, questions, suggestions or concerns';'Email: piradaboonna@gmail.com';'Email: mink.ost26@gmail.com'},'Contact Us');
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            url = 'https://sites.google.com/view/mspec/';
            web(url)
        end

        % Callback function: PushTool9, SaveMenu
        function PushTool9Clicked(app, event)
            MSpecController.saveChanges(app);
        end

        % Clicked callback: PushTool6
        function PushTool6Clicked(app, event)
            url = 'https://sites.google.com/view/mspec/';
            web(url)
        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
            app.TabGroup.SelectedTab = app.ImportTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MSPECAppUIFigure = uifigure('Visible', 'off');
            app.MSPECAppUIFigure.Name = 'MSPEC App';
            app.MSPECAppUIFigure.Icon = 'MS_icon.png';
            app.MSPECAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @MSPECAppUIFigureCloseRequest, true);
            app.Menu = uimenu(app.MSPECAppUIFigure);
            app.Menu.Text = 'Menu';
            app.NewProjectMenu = uimenu(app.Menu);
            app.NewProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @PushToolClicked, true);
            app.NewProjectMenu.Text = 'New Project';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool9Clicked, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.Menu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool8Clicked, true);
            app.SaveAsMenu.Text = 'Save As';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @MSPECAppUIFigureCloseRequest, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.MSPECAppUIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.ContactUsMenu = uimenu(app.HelpMenu);
            app.ContactUsMenu.MenuSelectedFcn = createCallbackFcn(app, @ContactUsMenuSelected, true);
            app.ContactUsMenu.Text = 'Contact Us';
            app.Toolbar = uitoolbar(app.MSPECAppUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'New Project'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'icons8-plus-math-80.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Open Project'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'Import.png';
            app.PushTool3.Separator = 'on';
            app.PushTool9 = uipushtool(app.Toolbar);
            app.PushTool9.Tag = 'Save';
            app.PushTool9.ClickedCallback = createCallbackFcn(app, @PushTool9Clicked, true);
            app.PushTool9.Icon = 'icons8-save-96.png';
            app.PushTool9.Separator = 'on';
            app.PushTool8 = uipushtool(app.Toolbar);
            app.PushTool8.Tooltip = {'Save as'};
            app.PushTool8.ClickedCallback = createCallbackFcn(app, @PushTool8Clicked, true);
            app.PushTool8.Icon = 'save_as.png';
            app.PushTool8.Separator = 'on';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = 'icons8-download-96.png';
            app.PushTool4.Separator = 'on';
            app.PushTool7 = uipushtool(app.Toolbar);
            app.PushTool7.ClickedCallback = createCallbackFcn(app, @PushTool7Clicked, true);
            app.PushTool7.Icon = 'icons8-report-file-128.png';
            app.PushTool7.Separator = 'on';
            app.PushTool6 = uipushtool(app.Toolbar);
            app.PushTool6.ClickedCallback = createCallbackFcn(app, @PushTool6Clicked, true);
            app.PushTool6.Icon = 'icons8-user-manual-80.png';
            app.PushTool6.Separator = 'on';
            app.TabGroup = uitabgroup(app.MSPECAppUIFigure);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.WelcometoMSpecLabel = uilabel(app.ImportTab);
            app.WelcometoMSpecLabel.Text = 'Welcome to MSpec !';
            app.ImportyourmassspectrometrydatatobeginLabel = uilabel(app.ImportTab);
            app.ImportyourmassspectrometrydatatobeginLabel.Text = 'Import your mass spectrometry data to begin :)';
            app.Import_ImportButton = uibutton(app.ImportTab, 'push');
            app.Import_ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_Pushed, true);
            app.Import_ImportButton.Text = 'Import';
            app.CreateNewProjectPanel = uipanel(app.ImportTab);
            app.CreateNewProjectPanel.Title = 'Create New Project';
            app.ProjectNameEditFieldLabel = uilabel(app.CreateNewProjectPanel);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectNameEditField = uieditfield(app.CreateNewProjectPanel, 'text');
            app.DataTypesButtonGroup = uibuttongroup(app.CreateNewProjectPanel);
            app.DataTypesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataTypesButtonGroupSelectionChanged, true);
            app.DataTypesButtonGroup.Title = 'Data Types';
            app.MassSpectra1DButton = uiradiobutton(app.DataTypesButtonGroup);
            app.MassSpectra1DButton.Text = 'Mass Spectra (1D)';
            app.MassSpectra1DButton.Value = true;
            app.Imaging2DButton = uiradiobutton(app.DataTypesButtonGroup);
            app.Imaging2DButton.Text = 'Imaging (2D)';
            app.DimensionLabel = uilabel(app.CreateNewProjectPanel);
            app.DimensionLabel.Text = 'Dimension';
            app.WidthField = uieditfield(app.CreateNewProjectPanel, 'numeric');
            app.WidthField.Editable = 'off';
            app.xLabel = uilabel(app.CreateNewProjectPanel);
            app.xLabel.Text = 'x';
            app.HeightField = uieditfield(app.CreateNewProjectPanel, 'numeric');
            app.HeightField.Editable = 'off';
            app.Image = uiimage(app.CreateNewProjectPanel);
            app.Image.Tooltip = {'Dimension of your data. In case of imaging data, input " width x height ".'};
            app.Image.ImageSource = 'info_icon.png';
            app.NumberofMassSpectraLabel = uilabel(app.CreateNewProjectPanel);
            app.NumberofMassSpectraLabel.Text = {'Number of '; 'Mass Spectra'};
            app.NumberofMassSpectraEditField = uieditfield(app.CreateNewProjectPanel, 'numeric');
            app.NumberofMassSpectraEditField.Editable = 'off';
            app.Import_CreateProjectButton = uibutton(app.CreateNewProjectPanel, 'push');
            app.Import_CreateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @Import_CreateProjectButtonPushed, true);
            app.Import_CreateProjectButton.Enable = 'off';
            app.Import_CreateProjectButton.Text = 'Create';
            app.DescriptionTextAreaLabel_2 = uilabel(app.CreateNewProjectPanel);
            app.DescriptionTextAreaLabel_2.Text = 'Description';
            app.DescriptionEditField = uitextarea(app.CreateNewProjectPanel);
            app.RecentFilesPanel = uipanel(app.ImportTab);
            app.RecentFilesPanel.Title = 'Recent Files';
            app.RecentFileListBox = uilistbox(app.RecentFilesPanel);
            app.OpenButton = uibutton(app.RecentFilesPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ImportStatusLabel = uilabel(app.ImportTab);
            app.ImportStatusLabel.Text = '';
            app.Image2_2 = uiimage(app.ImportTab);
            app.Image2_2.ImageSource = 'Mspec_logo.png';
            app.PreprocessingLabel = uilabel(app.ImportTab);
            app.PreprocessingLabel.Text = 'Preprocessing';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Pre-processing';
            app.Preprocessing_PreprocessedMSPlot = uiaxes(app.PreprocessingTab);
            title(app.Preprocessing_PreprocessedMSPlot, 'Pre-Processed Mass Spectra')
            xlabel(app.Preprocessing_PreprocessedMSPlot, 'Mass/Charge (m/z)')
            ylabel(app.Preprocessing_PreprocessedMSPlot, 'Relative Intensity')
            app.Preprocessing_RawMSPlot = uiaxes(app.PreprocessingTab);
            title(app.Preprocessing_RawMSPlot, 'Raw Mass Spectra')
            xlabel(app.Preprocessing_RawMSPlot, 'Mass/Charge (m/z)')
            ylabel(app.Preprocessing_RawMSPlot, 'Relative Intensity')
            app.PreProcessingToolLabel = uilabel(app.PreprocessingTab);
            app.PreProcessingToolLabel.Text = 'Pre-Processing Tool';
            app.BaselineCorrectionPanel = uipanel(app.PreprocessingTab);
            app.BaselineCorrectionPanel.Title = 'Baseline Correction';
            app.Preprocessing_WindowSizeEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_WindowSizeEditField.Value = 0.5;
            app.WindowSizeEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.Preprocessing_StepsizeEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_StepsizeEditField.Value = 0.5;
            app.StepsizeEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.Preprocessing_QuantilevalueEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_QuantilevalueEditField.Limits = [0 Inf];
            app.Preprocessing_QuantilevalueEditField.Value = 0.5;
            app.QuantilevalueEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.QuantilevalueEditFieldLabel.Text = 'Quantile value';
            app.PeakAlignmentPanel = uipanel(app.PreprocessingTab);
            app.PeakAlignmentPanel.Title = 'Peak Alignment';
            app.Preprocessing_ReferenceSpectrumEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_ReferenceSpectrumEditField.Value = 2;
            app.ReferenceSpectrumEditFieldLabel = uilabel(app.PeakAlignmentPanel);
            app.ReferenceSpectrumEditFieldLabel.Text = 'Reference Spectrum';
            app.Preprocessing_MinimumsegementsizeallowedEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_MinimumsegementsizeallowedEditField.Value = 10;
            app.MinimumsegmentsizeallowedLabel = uilabel(app.PeakAlignmentPanel);
            app.MinimumsegmentsizeallowedLabel.Text = 'Minimum segment size allowed';
            app.Preprocessing_MaximumshiftallowedEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_MaximumshiftallowedEditField.Value = 50;
            app.MaximumshiftallowedEditFieldLabel = uilabel(app.PeakAlignmentPanel);
            app.MaximumshiftallowedEditFieldLabel.Text = 'Maximum shift allowed';
            app.PlotPanel = uipanel(app.PreprocessingTab);
            app.PlotPanel.Title = 'Plot';
            app.SpectrumtodisplayEditFieldLabel = uilabel(app.PlotPanel);
            app.SpectrumtodisplayEditFieldLabel.Text = 'Spectrum to display';
            app.Preprocessing_SpectrumtodisplayEditField = uieditfield(app.PlotPanel, 'text');
            app.Preprocessing_SectionofinterestLabel = uilabel(app.PlotPanel);
            app.Preprocessing_SectionofinterestLabel.Text = 'Section of interest:';
            app.StartingpointEditFieldLabel = uilabel(app.PlotPanel);
            app.StartingpointEditFieldLabel.Text = 'Starting point';
            app.Preprocessing_StartingpointEditField = uieditfield(app.PlotPanel, 'text');
            app.EndingpointEditFieldLabel = uilabel(app.PlotPanel);
            app.EndingpointEditFieldLabel.Text = 'Ending point';
            app.Preprocessing_EndingpointEditField = uieditfield(app.PlotPanel, 'text');
            app.Preprocessing_PlotButton = uibutton(app.PlotPanel, 'push');
            app.Preprocessing_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @Preprocessing_PlotButtonPushed, true);
            app.Preprocessing_PlotButton.Text = 'Plot';
            app.Preprocessing_ExportButton = uibutton(app.PreprocessingTab, 'push');
            app.Preprocessing_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @Preprocessing_ExportButtonPushed, true);
            app.Preprocessing_ExportButton.Text = 'Export';
            app.Preprocessing_SaveButton = uibutton(app.PreprocessingTab, 'push');
            app.Preprocessing_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @Preprocessing_SaveButtonPushed, true);
            app.Preprocessing_SaveButton.Text = 'Next';
            app.NormalizationTab = uitab(app.TabGroup);
            app.NormalizationTab.Title = 'Normalization';
            app.Normalization_ProcessedMSPlot = uiaxes(app.NormalizationTab);
            title(app.Normalization_ProcessedMSPlot, 'Aligned MS Spectra')
            xlabel(app.Normalization_ProcessedMSPlot, 'Mass/Charge (m/z)')
            ylabel(app.Normalization_ProcessedMSPlot, 'Relative Intensity')
            app.Normalization_NormalizedMSPlot = uiaxes(app.NormalizationTab);
            title(app.Normalization_NormalizedMSPlot, 'Normalized Spectra')
            xlabel(app.Normalization_NormalizedMSPlot, 'Mass/Charge (m/z)')
            ylabel(app.Normalization_NormalizedMSPlot, 'Relative Intensity')
            app.NormalizationMethodsButtonGroup = uibuttongroup(app.NormalizationTab);
            app.NormalizationMethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormalizationMethodsButtonGroupSelectionChanged, true);
            app.NormalizationMethodsButtonGroup.Title = 'Normalization Methods';
            app.Normalization_ioncountsButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_ioncountsButton.Text = 'ion counts';
            app.Normalization_ioncountsButton.Value = true;
            app.Normalization_ReferencePeakButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_ReferencePeakButton.Text = 'Reference Peak';
            app.pvalueSpinnerLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.pvalueSpinnerLabel.Enable = 'off';
            app.pvalueSpinnerLabel.Text = 'p-value';
            app.Normalization_pvalueSpinner = uispinner(app.NormalizationMethodsButtonGroup);
            app.Normalization_pvalueSpinner.Limits = [1 Inf];
            app.Normalization_pvalueSpinner.ValueChangedFcn = createCallbackFcn(app, @Normalization_pvalueSpinnerValueChanged, true);
            app.Normalization_pvalueSpinner.Enable = 'off';
            app.Normalization_pvalueSpinner.Value = 1;
            app.Normalization_pnormButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_pnormButton.Text = 'p-norm';
            app.Normalization_MedianButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_MedianButton.Text = 'Median';
            app.Normalization_NoiseLevelButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_NoiseLevelButton.Text = 'Noise Level';
            app.Normalize_StartButton = uibutton(app.NormalizationMethodsButtonGroup, 'push');
            app.Normalize_StartButton.ButtonPushedFcn = createCallbackFcn(app, @Normalize_StartButtonPushed, true);
            app.Normalize_StartButton.Text = 'Start';
            app.ReferenceMZLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.ReferenceMZLabel.Text = {'Reference'; 'M/Z'};
            app.Normalization_ReferenceMZEditField = uieditfield(app.NormalizationMethodsButtonGroup, 'numeric');
            app.Normalization_ReferenceMZEditField.ValueDisplayFormat = '%5.4f';
            app.Normalization_ReferenceMZEditField.ValueChangedFcn = createCallbackFcn(app, @Normalization_ReferenceMZEditFieldValueChanged, true);
            app.Normalization_ReferenceMZEditField.Editable = 'off';
            app.Normalization_EditRefMZ = uibutton(app.NormalizationMethodsButtonGroup, 'push');
            app.Normalization_EditRefMZ.ButtonPushedFcn = createCallbackFcn(app, @Normalization_EditRefMZPushed, true);
            app.Normalization_EditRefMZ.Text = 'Edit';
            app.Normalization_MaxIntensityButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_MaxIntensityButton.Text = 'Maximum Intensity';
            app.ViewOptionButtonGroup = uibuttongroup(app.NormalizationTab);
            app.ViewOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewOptionButtonGroupSelectionChanged, true);
            app.ViewOptionButtonGroup.Title = ' View Option';
            app.Normalization_AllSpectraButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.Normalization_AllSpectraButton.Text = 'All Spectra';
            app.Normalization_AllSpectraButton.Value = true;
            app.Normalization_SingleSpectrumButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.Normalization_SingleSpectrumButton.Text = 'Single Spectrum';
            app.SamplePointSpinnerLabel = uilabel(app.ViewOptionButtonGroup);
            app.SamplePointSpinnerLabel.Enable = 'off';
            app.SamplePointSpinnerLabel.Text = 'Sample Point';
            app.Normalization_SamplePointSpinner = uispinner(app.ViewOptionButtonGroup);
            app.Normalization_SamplePointSpinner.Limits = [1 80];
            app.Normalization_SamplePointSpinner.ValueChangedFcn = createCallbackFcn(app, @Normalization_SamplePointSpinnerValueChanged, true);
            app.Normalization_SamplePointSpinner.Enable = 'off';
            app.Normalization_SamplePointSpinner.Value = 1;
            app.Normalization_SelectDataTable = uitable(app.ViewOptionButtonGroup);
            app.Normalization_SelectDataTable.ColumnName = {'Sample Point'; 'Display'};
            app.Normalization_SelectDataTable.RowName = {};
            app.Normalization_SelectDataTable.ColumnSortable = true;
            app.Normalization_SelectDataTable.ColumnEditable = [false true];
            app.Normalization_SelectDataTable.CellEditCallback = createCallbackFcn(app, @Normalization_SelectDataTableCellEdit, true);
            app.Normalization_SelectDataTable.Enable = 'off';
            app.Normalization_MultipleSpectraButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.Normalization_MultipleSpectraButton.Text = 'Multiple Spectra';
            app.ExportFilesPanel = uipanel(app.NormalizationTab);
            app.ExportFilesPanel.Title = 'Export Files';
            app.Normalization_ExportCSVNormalizedDataButton = uibutton(app.ExportFilesPanel, 'push');
            app.Normalization_ExportCSVNormalizedDataButton.ButtonPushedFcn = createCallbackFcn(app, @Normalization_ExportCSVNormalizedDataButtonPushed, true);
            app.Normalization_ExportCSVNormalizedDataButton.Text = 'Export Normalized Data ';
            app.Normalization_SaveButton = uibutton(app.ExportFilesPanel, 'push');
            app.Normalization_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @Normalization_SaveButtonPushed, true);
            app.Normalization_SaveButton.Text = 'Next';
            app.Normalization_DataTable = uitable(app.NormalizationTab);
            app.Normalization_DataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Normalization_DataTable.RowName = {};
            app.DataNormalizationLabel = uilabel(app.NormalizationTab);
            app.DataNormalizationLabel.Text = 'Data Normalization';
            app.PeakDetectionTab = uitab(app.TabGroup);
            app.PeakDetectionTab.Title = 'Peak Detection';
            app.Detection_PeakDetectionPlot = uiaxes(app.PeakDetectionTab);
            title(app.Detection_PeakDetectionPlot, 'Peak Detection')
            xlabel(app.Detection_PeakDetectionPlot, 'Mass/Charge (m/z)')
            ylabel(app.Detection_PeakDetectionPlot, 'Relative Intensity')
            app.Binning_PeakBinningPlot = uiaxes(app.PeakDetectionTab);
            title(app.Binning_PeakBinningPlot, 'Common Mass/Charge (M/Z) Locations')
            xlabel(app.Binning_PeakBinningPlot, 'Mass/Charge (m/z)')
            ylabel(app.Binning_PeakBinningPlot, 'Relative Intensity')
            app.Binning_AlignedPeakBinningPlot = uiaxes(app.PeakDetectionTab);
            title(app.Binning_AlignedPeakBinningPlot, {'Peaks Aligned to the'; 'Common Mass/Charge (M/Z)'})
            xlabel(app.Binning_AlignedPeakBinningPlot, 'Mass/Charge (m/z)')
            ylabel(app.Binning_AlignedPeakBinningPlot, 'Relative Intensity')
            app.PeakDetectionPanel = uipanel(app.PeakDetectionTab);
            app.PeakDetectionPanel.Title = 'Peak Detection';
            app.BASEEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.BASEEditFieldLabel.Enable = 'off';
            app.BASEEditFieldLabel.Text = 'BASE';
            app.Detection_BASEEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.Detection_BASEEditField.Enable = 'off';
            app.Detection_BASEEditField.Value = 12;
            app.HEIGHTFILTEREditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.HEIGHTFILTEREditFieldLabel.Enable = 'off';
            app.HEIGHTFILTEREditFieldLabel.Text = 'HEIGHTFILTER';
            app.Detection_HEIGHTFILTEREditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.Detection_HEIGHTFILTEREditField.Enable = 'off';
            app.Detection_HEIGHTFILTEREditField.Value = 1;
            app.MULTIPLIEREditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.MULTIPLIEREditFieldLabel.Enable = 'off';
            app.MULTIPLIEREditFieldLabel.Text = 'MULTIPLIER';
            app.Detection_MULTIPLIEREditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.Detection_MULTIPLIEREditField.Enable = 'off';
            app.Detection_MULTIPLIEREditField.Value = 10;
            app.PeakThresholdEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.PeakThresholdEditFieldLabel.Enable = 'off';
            app.PeakThresholdEditFieldLabel.Text = 'Peak Threshold';
            app.Detection_PeakThresholdEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.Detection_PeakThresholdEditField.Enable = 'off';
            app.Detection_PeakThresholdEditField.Value = 300;
            app.Detection_StartDetectionButton = uibutton(app.PeakDetectionPanel, 'push');
            app.Detection_StartDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @Detection_StartDetectionButtonPushed, true);
            app.Detection_StartDetectionButton.Text = 'Start';
            app.MethodDropDownLabel_2 = uilabel(app.PeakDetectionPanel);
            app.MethodDropDownLabel_2.Text = 'Method';
            app.Detection_MethodDropDown = uidropdown(app.PeakDetectionPanel);
            app.Detection_MethodDropDown.Items = {'Auto', 'Self Adjustments'};
            app.Detection_MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @Detection_MethodDropDownValueChanged, true);
            app.Detection_MethodDropDown.Value = 'Auto';
            app.BiningPanel = uipanel(app.PeakDetectionTab);
            app.BiningPanel.Title = 'Bining';
            app.MethodDropDownLabel = uilabel(app.BiningPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.Binning_MethodDropDown = uidropdown(app.BiningPanel);
            app.Binning_MethodDropDown.Items = {'Hierarchical Clustering', 'Dynamic Programming'};
            app.Binning_MethodDropDown.ItemsData = {'1'};
            app.Binning_MethodDropDown.Value = '1';
            app.CriterionDropDownLabel = uilabel(app.BiningPanel);
            app.CriterionDropDownLabel.Text = 'Criterion';
            app.Binning_CriterionDropDown = uidropdown(app.BiningPanel);
            app.Binning_CriterionDropDown.Items = {'DISTANCE'};
            app.Binning_CriterionDropDown.ItemsData = {'distance'};
            app.Binning_CriterionDropDown.Value = 'distance';
            app.Detection_StartDetectionButton_2 = uibutton(app.BiningPanel, 'push');
            app.Detection_StartDetectionButton_2.ButtonPushedFcn = createCallbackFcn(app, @Detection_StartDetectionButton_2Pushed, true);
            app.Detection_StartDetectionButton_2.Text = 'Start';
            app.CUTOFFSpinnerLabel = uilabel(app.BiningPanel);
            app.CUTOFFSpinnerLabel.Text = 'CUTOFF';
            app.CUTOFFSpinner = uispinner(app.BiningPanel);
            app.CUTOFFSpinner.Step = 0.1;
            app.CUTOFFSpinner.Limits = [0 Inf];
            app.CUTOFFSpinner.ValueDisplayFormat = '%5.2f';
            app.CUTOFFSpinner.Value = 1;
            app.ExportPeakListButton = uibutton(app.PeakDetectionTab, 'push');
            app.ExportPeakListButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPeakListButtonPushed, true);
            app.ExportPeakListButton.Text = 'Export  Peak List';
            app.PeakDetectionLabel = uilabel(app.PeakDetectionTab);
            app.PeakDetectionLabel.Text = 'Peak Detection';
            app.SpectrumtodisplayoptionalLabel = uilabel(app.PeakDetectionTab);
            app.SpectrumtodisplayoptionalLabel.Text = {'Spectrum'; 'to display'; '(optional)'};
            app.Detection_SpectrumtodisplayEditField = uieditfield(app.PeakDetectionTab, 'text');
            app.NextButton = uibutton(app.PeakDetectionTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SampleAlignmentPanel = uipanel(app.PeakDetectionTab);
            app.SampleAlignmentPanel.Title = 'Sample Alignment';
            app.MethodDropDownLabel_3 = uilabel(app.SampleAlignmentPanel);
            app.MethodDropDownLabel_3.Text = 'Method';
            app.Binning_MethodDropDown_2 = uidropdown(app.SampleAlignmentPanel);
            app.Binning_MethodDropDown_2.Items = {'Auto'};
            app.Binning_MethodDropDown_2.ItemsData = {'1'};
            app.Binning_MethodDropDown_2.Value = '1';
            app.Detection_StartDetectionButton_3 = uibutton(app.SampleAlignmentPanel, 'push');
            app.Detection_StartDetectionButton_3.ButtonPushedFcn = createCallbackFcn(app, @Detection_StartDetectionButton_3Pushed, true);
            app.Detection_StartDetectionButton_3.Text = 'Start';
            app.MaximumallowabledistanceLabel = uilabel(app.SampleAlignmentPanel);
            app.MaximumallowabledistanceLabel.Enable = 'off';
            app.MaximumallowabledistanceLabel.Text = {'Maximum'; 'allowable distance '};
            app.Detection_BANDEditField = uieditfield(app.SampleAlignmentPanel, 'numeric');
            app.Detection_BANDEditField.Enable = 'off';
            app.Detection_BANDEditField.Value = 12;
            app.TotalnoofpeaksEditFieldLabel = uilabel(app.PeakDetectionTab);
            app.TotalnoofpeaksEditFieldLabel.Text = 'Total no. of peaks';
            app.TotalnoofpeaksEditField = uieditfield(app.PeakDetectionTab, 'numeric');
            app.TotalnoofpeaksEditField.Editable = 'off';
            app.TotalnoofcommonpeaksEditFieldLabel = uilabel(app.PeakDetectionTab);
            app.TotalnoofcommonpeaksEditFieldLabel.Text = 'Total no. of common peaks';
            app.TotalnoofcommonpeaksEditField = uieditfield(app.PeakDetectionTab, 'numeric');
            app.TotalnoofcommonpeaksEditField.Editable = 'off';
            app.ExportCMZButton = uibutton(app.PeakDetectionTab, 'push');
            app.ExportCMZButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCMZButtonPushed, true);
            app.ExportCMZButton.Text = 'Export  CMZ';
            app.PeakBinningTab = uitab(app.TabGroup);
            app.PeakBinningTab.Title = 'Peak Binning';
            app.PeakBinningFinalPlot = uiaxes(app.PeakBinningTab);
            title(app.PeakBinningFinalPlot, 'Peak Binning')
            xlabel(app.PeakBinningFinalPlot, 'Mass/Charge Bins')
            ylabel(app.PeakBinningFinalPlot, 'Accummulative Intensity')
            zlabel(app.PeakBinningFinalPlot, 'Z')
            app.Binning_EdgeListPlot = uiaxes(app.PeakBinningTab);
            xlabel(app.Binning_EdgeListPlot, 'Mass/Charge (m/z)')
            ylabel(app.Binning_EdgeListPlot, 'Y')
            zlabel(app.Binning_EdgeListPlot, 'Z')
            app.Binning_EdgeListPlot.YLim = [0 2.22044604925031e-16];
            app.PeakBinningPanel = uipanel(app.PeakBinningTab);
            app.PeakBinningPanel.Title = 'Peak Binning';
            app.Binning_StartBinningButton = uibutton(app.PeakBinningPanel, 'push');
            app.Binning_StartBinningButton.ButtonPushedFcn = createCallbackFcn(app, @Binning_StartBinningButtonPushed, true);
            app.Binning_StartBinningButton.Text = 'Start';
            app.MethodDropDownLabel_4 = uilabel(app.PeakBinningPanel);
            app.MethodDropDownLabel_4.Text = 'Method';
            app.Binning_BinningMethod = uidropdown(app.PeakBinningPanel);
            app.Binning_BinningMethod.Items = {'Strict', 'Relaxed'};
            app.Binning_BinningMethod.Value = 'Strict';
            app.MaximumnoofpeaksineachbinLabel = uilabel(app.PeakBinningPanel);
            app.MaximumnoofpeaksineachbinLabel.Text = {'Maximum no. of '; 'peaks in each bin'};
            app.Binning_NumberofBinsSpinner = uispinner(app.PeakBinningPanel);
            app.Binning_NumberofBinsSpinner.Limits = [1 2];
            app.Binning_NumberofBinsSpinner.Value = 1;
            app.ToleranceEditFieldLabel = uilabel(app.PeakBinningPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.Binning_ToleranceEditField = uieditfield(app.PeakBinningPanel, 'numeric');
            app.Binning_ToleranceEditField.Limits = [0 Inf];
            app.Binning_ExportButton = uibutton(app.PeakBinningPanel, 'push');
            app.Binning_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @Binning_ExportButtonPushed, true);
            app.Binning_ExportButton.Text = 'Export Data';
            app.Binning_ImportEdgesCheckBox = uicheckbox(app.PeakBinningPanel);
            app.Binning_ImportEdgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @Binning_ImportEdgesCheckBoxValueChanged, true);
            app.Binning_ImportEdgesCheckBox.Text = '  Import Edges';
            app.Binning_ImportEdgeButton = uibutton(app.PeakBinningPanel, 'push');
            app.Binning_ImportEdgeButton.ButtonPushedFcn = createCallbackFcn(app, @Binning_ImportEdgeButtonPushed, true);
            app.Binning_ImportEdgeButton.Icon = 'file_icon.png';
            app.Binning_ImportEdgeButton.Enable = 'off';
            app.Binning_ImportEdgeButton.Text = '';
            app.Binning_FileLabel = uilabel(app.PeakBinningPanel);
            app.Binning_FileLabel.Text = 'Choose File . . .';
            app.Binning_ExportBinButton = uibutton(app.PeakBinningPanel, 'push');
            app.Binning_ExportBinButton.ButtonPushedFcn = createCallbackFcn(app, @Binning_ExportBinButtonPushed, true);
            app.Binning_ExportBinButton.Text = 'Export Bin Edges';
            app.Binning_ViewOptionButtonGroup = uibuttongroup(app.PeakBinningTab);
            app.Binning_ViewOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Binning_ViewOptionButtonGroupSelectionChanged, true);
            app.Binning_ViewOptionButtonGroup.Title = ' View Option';
            app.Binning_AllSpectraButton = uiradiobutton(app.Binning_ViewOptionButtonGroup);
            app.Binning_AllSpectraButton.Text = 'All Spectra';
            app.Binning_AllSpectraButton.Value = true;
            app.Binning_SingleSpectrumButton = uiradiobutton(app.Binning_ViewOptionButtonGroup);
            app.Binning_SingleSpectrumButton.Text = 'Single Spectrum';
            app.SamplePointSpinnerLabel_2 = uilabel(app.Binning_ViewOptionButtonGroup);
            app.SamplePointSpinnerLabel_2.Enable = 'off';
            app.SamplePointSpinnerLabel_2.Text = 'Sample Point';
            app.Binning_SamplePointSpinner = uispinner(app.Binning_ViewOptionButtonGroup);
            app.Binning_SamplePointSpinner.Limits = [1 80];
            app.Binning_SamplePointSpinner.ValueChangedFcn = createCallbackFcn(app, @Binning_SamplePointSpinnerValueChanged, true);
            app.Binning_SamplePointSpinner.Enable = 'off';
            app.Binning_SamplePointSpinner.Value = 1;
            app.Binning_SelectDataTable = uitable(app.Binning_ViewOptionButtonGroup);
            app.Binning_SelectDataTable.ColumnName = {'Sample'; 'Display'};
            app.Binning_SelectDataTable.RowName = {};
            app.Binning_SelectDataTable.ColumnSortable = true;
            app.Binning_SelectDataTable.ColumnEditable = [false true];
            app.Binning_SelectDataTable.CellEditCallback = createCallbackFcn(app, @Binning_SelectDataTableCellEdit, true);
            app.Binning_SelectDataTable.Enable = 'off';
            app.Binning_MultipleSpectraButton = uiradiobutton(app.Binning_ViewOptionButtonGroup);
            app.Binning_MultipleSpectraButton.Text = 'Multiple Spectra';
            app.CMZLabel = uilabel(app.PeakBinningTab);
            app.CMZLabel.Text = 'CMZ';
            app.CalculatedEdgesLabel = uilabel(app.PeakBinningTab);
            app.CalculatedEdgesLabel.Text = {'Calculated'; 'Edges'};
            app.Label_2 = uilabel(app.PeakBinningTab);
            app.Label_2.Text = '.';
            app.Label_3 = uilabel(app.PeakBinningTab);
            app.Label_3.Text = '*';
            app.PeakBinningLabel = uilabel(app.PeakBinningTab);
            app.PeakBinningLabel.Text = 'Peak Binning';
            app.Binning_Table = uitable(app.PeakBinningTab);
            app.Binning_Table.ColumnName = '';
            app.Binning_Table.RowName = {};
            app.ProjectInfoTab = uitab(app.TabGroup);
            app.ProjectInfoTab.Title = 'Project Info';
            app.AboutYourProjectPanel = uipanel(app.ProjectInfoTab);
            app.AboutYourProjectPanel.Title = 'About Your Project';
            app.ProjectNameEditField_2Label = uilabel(app.AboutYourProjectPanel);
            app.ProjectNameEditField_2Label.Text = 'Project Name';
            app.ProjectInfo_ProjectNameEditField = uieditfield(app.AboutYourProjectPanel, 'text');
            app.ProjectInfo_ProjectNameEditField.Editable = 'off';
            app.ImportedFileEditFieldLabel = uilabel(app.AboutYourProjectPanel);
            app.ImportedFileEditFieldLabel.Text = 'Imported File';
            app.ProjectInfo_ImportedFileEditField = uieditfield(app.AboutYourProjectPanel, 'text');
            app.ProjectInfo_ImportedFileEditField.Editable = 'off';
            app.DescriptionTextAreaLabel = uilabel(app.AboutYourProjectPanel);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.ProjectInfo_DescriptionTextArea = uitextarea(app.AboutYourProjectPanel);
            app.ProjectInfo_DescriptionTextArea.Editable = 'off';
            app.EditButton = uibutton(app.AboutYourProjectPanel, 'state');
            app.EditButton.ValueChangedFcn = createCallbackFcn(app, @EditButtonValueChanged, true);
            app.EditButton.Text = 'Edit';
            app.ReportGenerationPanel = uipanel(app.AboutYourProjectPanel);
            app.ReportGenerationPanel.Title = 'Report Generation';
            app.PeakDetectionCheckBox = uicheckbox(app.ReportGenerationPanel);
            app.PeakDetectionCheckBox.Enable = 'off';
            app.PeakDetectionCheckBox.Text = 'Peak Detection';
            app.NormalizationCheckBox = uicheckbox(app.ReportGenerationPanel);
            app.NormalizationCheckBox.Enable = 'off';
            app.NormalizationCheckBox.Text = 'Normalization';
            app.PreprocessingCheckBox = uicheckbox(app.ReportGenerationPanel);
            app.PreprocessingCheckBox.Enable = 'off';
            app.PreprocessingCheckBox.Text = 'Pre-processing';
            app.GeneratePDFReportButton = uibutton(app.ReportGenerationPanel, 'push');
            app.GeneratePDFReportButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePDFReportButtonPushed, true);
            app.GeneratePDFReportButton.Text = 'Generate PDF Report';
            app.PeakBinningCheckBox = uicheckbox(app.ReportGenerationPanel);
            app.PeakBinningCheckBox.Enable = 'off';
            app.PeakBinningCheckBox.Text = 'Peak Binning';
            app.StepsLabel = uilabel(app.ReportGenerationPanel);
            app.StepsLabel.Text = 'Steps';
            app.CreatedLabel = uilabel(app.AboutYourProjectPanel);
            app.CreatedLabel.Text = 'Created';
            app.ProjectInfo_CreatedDate = uieditfield(app.AboutYourProjectPanel, 'text');
            app.ProjectInfo_CreatedDate.Editable = 'off';
            app.DataExportPanel = uipanel(app.AboutYourProjectPanel);
            app.DataExportPanel.Title = 'Data Export';
            app.Export_PeakDetectionCheckBox = uicheckbox(app.DataExportPanel);
            app.Export_PeakDetectionCheckBox.Enable = 'off';
            app.Export_PeakDetectionCheckBox.Text = 'Peak Detection';
            app.Export_NormalizationCheckBox = uicheckbox(app.DataExportPanel);
            app.Export_NormalizationCheckBox.Enable = 'off';
            app.Export_NormalizationCheckBox.Text = 'Normalization';
            app.Export_PreprocessingCheckBox = uicheckbox(app.DataExportPanel);
            app.Export_PreprocessingCheckBox.Enable = 'off';
            app.Export_PreprocessingCheckBox.Text = 'Pre-processing';
            app.ExportCSVFilesButton = uibutton(app.DataExportPanel, 'push');
            app.ExportCSVFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVFilesButtonPushed, true);
            app.ExportCSVFilesButton.Text = 'Export CSV Files';
            app.Export_PeakBinningCheckBox = uicheckbox(app.DataExportPanel);
            app.Export_PeakBinningCheckBox.Enable = 'off';
            app.Export_PeakBinningCheckBox.Text = 'Peak Binning';
            app.StepsLabel_2 = uilabel(app.DataExportPanel);
            app.StepsLabel_2.Text = 'Steps';
            app.ExportSettingsButton = uibutton(app.DataExportPanel, 'push');
            app.ExportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSettingsButtonPushed, true);
            app.ExportSettingsButton.Text = 'Export Settings';
            app.Image2 = uiimage(app.ProjectInfoTab);
            app.Image2.ImageSource = 'Mspec_Application.png';
            app.AboutMSpecLabel = uilabel(app.ProjectInfoTab);
            app.AboutMSpecLabel.Text = 'About MSpec';
            app.Label_4 = uilabel(app.ProjectInfoTab);
            app.Label_4.Text = {'MSpec is an all-in-one solution for mass spectrometry data analysis.'; 'The application features tools such as pre-processing, normalization, binning,'; 'and classification to aid the user in the analysis of mass spectral data.'; 'The function parameters are also customizable to fit the user''s preferences.'; 'The processed data from each tool can also be exported as csv files and a final report'; 'of each project can also be generated and exported as pdf files.'; ''; 'For more information, please consult the MSpec documentation. '; 'If you need further assistance, please contact us via our email.'};
            app.Hyperlink = uihyperlink(app.ProjectInfoTab);
            app.Hyperlink.URL = 'https://sites.google.com/view/mspec/';
            app.Hyperlink.Text = 'Documentation';
            % Show the figure after all components are created
            app.MSPECAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSpecMainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MSPECAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MSPECAppUIFigure)
        end
    end
end